classdef design_page_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        soyourhappyandinloveZaynPanel_3  matlab.ui.container.Panel
        Hyperlink2_22                   matlab.ui.control.Hyperlink
        Image6_54                       matlab.ui.control.Image
        Image6_53                       matlab.ui.control.Image
        EditField_28                    matlab.ui.control.EditField
        soyourhappyanditscomplicatedZaynPanel  matlab.ui.container.Panel
        Hyperlink2_21                   matlab.ui.control.Hyperlink
        Image6_52                       matlab.ui.control.Image
        Image6_51                       matlab.ui.control.Image
        EditField_27                    matlab.ui.control.EditField
        soyourhappyandsingleZaynPanel   matlab.ui.container.Panel
        Hyperlink2_20                   matlab.ui.control.Hyperlink
        Image6_50                       matlab.ui.control.Image
        Image6_49                       matlab.ui.control.Image
        EditField_26                    matlab.ui.control.EditField
        soyourhappyandheartbrokenZaynPanel  matlab.ui.container.Panel
        Hyperlink2_19                   matlab.ui.control.Hyperlink
        Image6_48                       matlab.ui.control.Image
        Image6_47                       matlab.ui.control.Image
        EditField_25                    matlab.ui.control.EditField
        soyourhappyandinloveZaynPanel   matlab.ui.container.Panel
        Hyperlink2_18                   matlab.ui.control.Hyperlink
        Image6_46                       matlab.ui.control.Image
        Image6_45                       matlab.ui.control.Image
        EditField_24                    matlab.ui.control.EditField
        soyoursadanditscomplicatedNialPanel  matlab.ui.container.Panel
        Hyperlink2_17                   matlab.ui.control.Hyperlink
        Image6_44                       matlab.ui.control.Image
        Image6_43                       matlab.ui.control.Image
        EditField_23                    matlab.ui.control.EditField
        soyoursadandsingleNiallPanel    matlab.ui.container.Panel
        Hyperlink2_16                   matlab.ui.control.Hyperlink
        Image6_42                       matlab.ui.control.Image
        Image6_41                       matlab.ui.control.Image
        EditField_22                    matlab.ui.control.EditField
        soyoursadandheartbrokenNiallpanel  matlab.ui.container.Panel
        Hyperlink2_15                   matlab.ui.control.Hyperlink
        Image6_40                       matlab.ui.control.Image
        Image6_39                       matlab.ui.control.Image
        EditField_21                    matlab.ui.control.EditField
        soyoursadandinloveNiallPanel    matlab.ui.container.Panel
        Hyperlink2_14                   matlab.ui.control.Hyperlink
        Image6_38                       matlab.ui.control.Image
        Image6_37                       matlab.ui.control.Image
        EditField_20                    matlab.ui.control.EditField
        soyourhappyanditscomplicatedNiallPanel  matlab.ui.container.Panel
        Hyperlink2_13                   matlab.ui.control.Hyperlink
        Image6_36                       matlab.ui.control.Image
        Image6_35                       matlab.ui.control.Image
        EditField_19                    matlab.ui.control.EditField
        soyourhappyandsingleNiallPanel  matlab.ui.container.Panel
        Hyperlink2_12                   matlab.ui.control.Hyperlink
        Image6_33                       matlab.ui.control.Image
        Image6_32                       matlab.ui.control.Image
        EditField_18                    matlab.ui.control.EditField
        soyourhappyandheartbrokenNiallPanel  matlab.ui.container.Panel
        Hyperlink2_11                   matlab.ui.control.Hyperlink
        Image6_31                       matlab.ui.control.Image
        Image6_30                       matlab.ui.control.Image
        EditField_17                    matlab.ui.control.EditField
        soyourhappyandinloveNiallPanel  matlab.ui.container.Panel
        Hyperlink2_10                   matlab.ui.control.Hyperlink
        Image6_29                       matlab.ui.control.Image
        Image6_28                       matlab.ui.control.Image
        EditField_16                    matlab.ui.control.EditField
        soyoursadanditscomplicatedHarryPanel  matlab.ui.container.Panel
        soyourhappyanditscomplicatedHarryPanel  matlab.ui.container.Panel
        Hyperlink2_5                    matlab.ui.control.Hyperlink
        Image6_19                       matlab.ui.control.Image
        Image6_18                       matlab.ui.control.Image
        Image6_17                       matlab.ui.control.Image
        EditField_11                    matlab.ui.control.EditField
        Hyperlink2_9                    matlab.ui.control.Hyperlink
        Image6_27                       matlab.ui.control.Image
        Image6_26                       matlab.ui.control.Image
        EditField_15                    matlab.ui.control.EditField
        soyoursadandsingleHarryPanel    matlab.ui.container.Panel
        Hyperlink2_8                    matlab.ui.control.Hyperlink
        Image6_25                       matlab.ui.control.Image
        Image6_24                       matlab.ui.control.Image
        EditField_14                    matlab.ui.control.EditField
        soyoursadandheartbrokenHarryPanel  matlab.ui.container.Panel
        Hyperlink2_7                    matlab.ui.control.Hyperlink
        Image6_23                       matlab.ui.control.Image
        Image6_22                       matlab.ui.control.Image
        EditField_13                    matlab.ui.control.EditField
        soyoursadandinloveHarryPanel    matlab.ui.container.Panel
        Hyperlink2_6                    matlab.ui.control.Hyperlink
        Image6_21                       matlab.ui.control.Image
        Image6_20                       matlab.ui.control.Image
        EditField_12                    matlab.ui.control.EditField
        WhichOneDirectionMemberAreYouPanel_Harry  matlab.ui.container.Panel
        continueharryButton             matlab.ui.control.Button
        TextArea_7                      matlab.ui.control.TextArea
        SoyoureHarryStylesbutwhatHarryStylessongareyouPanel  matlab.ui.container.Panel
        SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel  matlab.ui.container.Panel
        SubmitLiamButton                matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_5  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_3          matlab.ui.control.RadioButton
        SingleandchillingButton_3       matlab.ui.control.RadioButton
        Imheartbroken3Button_3          matlab.ui.control.RadioButton
        IminloveButton_3                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_5   matlab.ui.container.ButtonGroup
        SadButton_5                     matlab.ui.control.RadioButton
        HappyButton_5                   matlab.ui.control.RadioButton
        SubmitHarryButton               matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_6          matlab.ui.control.RadioButton
        SingleandchillingButton_6       matlab.ui.control.RadioButton
        Imheartbroken3Button_6          matlab.ui.control.RadioButton
        IminloveButton_6                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup     matlab.ui.container.ButtonGroup
        SadButton                       matlab.ui.control.RadioButton
        HappyButton                     matlab.ui.control.RadioButton
        soyourhappyandheartbrokenHarryPanel  matlab.ui.container.Panel
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Image6_11                       matlab.ui.control.Image
        Image6_10                       matlab.ui.control.Image
        Image6_9                        matlab.ui.control.Image
        EditField_9                     matlab.ui.control.EditField
        soyourhappyandsingleHarryPanel  matlab.ui.container.Panel
        Hyperlink2_4                    matlab.ui.control.Hyperlink
        Image6_16                       matlab.ui.control.Image
        Image6_15                       matlab.ui.control.Image
        Image6_14                       matlab.ui.control.Image
        EditField_10                    matlab.ui.control.EditField
        soyouresadandcomplicatedheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Image6_7                        matlab.ui.control.Image
        EditField_7                     matlab.ui.control.EditField
        soyouresadandinloveheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Image6_6                        matlab.ui.control.Image
        EditField_6                     matlab.ui.control.EditField
        soyourehappyandcomplicatedheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        soyouresadandsingleheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Image6_8                        matlab.ui.control.Image
        EditField_8                     matlab.ui.control.EditField
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Image6_5                        matlab.ui.control.Image
        EditField_5                     matlab.ui.control.EditField
        soyourehappyandsingleheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Image6_4                        matlab.ui.control.Image
        EditField_4                     matlab.ui.control.EditField
        soyourehappyandheartbrokenheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Image6_3                        matlab.ui.control.Image
        EditField_3                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink                       matlab.ui.control.Hyperlink
        Image6_2                        matlab.ui.control.Image
        EditField_2                     matlab.ui.control.EditField
        SoyoureNiallHoranbutwhatNiallHoransongareyouPanel  matlab.ui.container.Panel
        SubmitNiallButton               matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_4  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_2          matlab.ui.control.RadioButton
        SingleandchillingButton_2       matlab.ui.control.RadioButton
        Imheartbroken3Button_2          matlab.ui.control.RadioButton
        IminloveButton_2                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_4   matlab.ui.container.ButtonGroup
        SadButton_4                     matlab.ui.control.RadioButton
        HappyButton_4                   matlab.ui.control.RadioButton
        SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel  matlab.ui.container.Panel
        SubmitZaynButton                matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_3  matlab.ui.container.ButtonGroup
        ItscomplicatedButton            matlab.ui.control.RadioButton
        SingleandchillingButton         matlab.ui.control.RadioButton
        Imheartbroken3Button            matlab.ui.control.RadioButton
        IminloveButton                  matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_3   matlab.ui.container.ButtonGroup
        SadButton_3                     matlab.ui.control.RadioButton
        HappyButton_3                   matlab.ui.control.RadioButton
        SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel  matlab.ui.container.Panel
        WhatsyourrelationshipstatusButtonGroup_6  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_4          matlab.ui.control.RadioButton
        SingleandchillingButton_4       matlab.ui.control.RadioButton
        Imheartbroken3Button_4          matlab.ui.control.RadioButton
        IminloveButton_4                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_6   matlab.ui.container.ButtonGroup
        SadButton_6                     matlab.ui.control.RadioButton
        HappyButton_6                   matlab.ui.control.RadioButton
        soyourehappyandinloveheresyourHarrysongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        Image6_13                       matlab.ui.control.Image
        Image6_12                       matlab.ui.control.Image
        EditField                       matlab.ui.control.EditField
        SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel  matlab.ui.container.Panel
        SubmitLouisButton               matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_2  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_5          matlab.ui.control.RadioButton
        SingleandchillingButton_5       matlab.ui.control.RadioButton
        Imheartbroken3Button_5          matlab.ui.control.RadioButton
        IminloveButton_5                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_2   matlab.ui.container.ButtonGroup
        SadButton_2                     matlab.ui.control.RadioButton
        HappyButton_2                   matlab.ui.control.RadioButton
        WhichOneDirectionMemberAreYouPanel_Liam  matlab.ui.container.Panel
        continueliamButton              matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_Taylor  matlab.ui.container.Panel
        continuetaylorButton            matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_Niall  matlab.ui.container.Panel
        continueniallButton             matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_louis  matlab.ui.container.Panel
        continuelouisButton             matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_zayn  matlab.ui.container.Panel
        continueButton_zayn             matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        WhichOneDirectionMemberAreYouLabel  matlab.ui.control.Label
        SUBMITButton                    matlab.ui.control.Button
        Q1WhatisyourfavoritefoodButtonGroup  matlab.ui.container.ButtonGroup
        MexicanfoodButton               matlab.ui.control.RadioButton
        pastaButton                     matlab.ui.control.RadioButton
        WhoneedsfoodwhenyouhavebeerButton  matlab.ui.control.RadioButton
        fastfoodButton                  matlab.ui.control.RadioButton
        fruitsandveggiesButton          matlab.ui.control.RadioButton
        Q2WhatisyourfavoriteseasonButtonGroup  matlab.ui.container.ButtonGroup
        IlikethemallButton              matlab.ui.control.RadioButton
        fallButton                      matlab.ui.control.RadioButton
        springButton                    matlab.ui.control.RadioButton
        winterButton                    matlab.ui.control.RadioButton
        summerButton                    matlab.ui.control.RadioButton
        Q3WhatwouldyouridealfirstdatebeButtonGroup  matlab.ui.container.ButtonGroup
        goingtoacarnivalButton          matlab.ui.control.RadioButton
        fancydinnerdateButton           matlab.ui.control.RadioButton
        stayinginandplayinggamesButton  matlab.ui.control.RadioButton
        goingforadriveButton            matlab.ui.control.RadioButton
        iceskatingButton                matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup_3  matlab.ui.container.ButtonGroup
        APMAEconwithanentrepreneurshipcertificateButton  matlab.ui.control.RadioButton
        VISAButton                      matlab.ui.control.RadioButton
        HistoryButton                   matlab.ui.control.RadioButton
        MCMButton                       matlab.ui.control.RadioButton
        LiteraryartsButton              matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup  matlab.ui.container.ButtonGroup
        BeaglepuppyButton               matlab.ui.control.RadioButton
        BlackkittenButton               matlab.ui.control.RadioButton
        GoldenretrieverpuppyButton      matlab.ui.control.RadioButton
        AustralianshepherdpuppyButton   matlab.ui.control.RadioButton
        CalicokittenButton              matlab.ui.control.RadioButton
        Q7WhatisyourfavoritecolorButtonGroup  matlab.ui.container.ButtonGroup
        RedButton                       matlab.ui.control.RadioButton
        YellowButton                    matlab.ui.control.RadioButton
        OrangeButton                    matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        Q6WhichroledoyouhaveinyourfriendgroupButtonGroup  matlab.ui.container.ButtonGroup
        DadfriendButton                 matlab.ui.control.RadioButton
        TheonepeoplegotoforadviceButton  matlab.ui.control.RadioButton
        CrazyuncleButton                matlab.ui.control.RadioButton
        ClassclownButton                matlab.ui.control.RadioButton
        MomfriendButton                 matlab.ui.control.RadioButton
        Q5WhichwouldyouratherdoinyourfreetimeButtonGroup  matlab.ui.container.ButtonGroup
        GooutButton                     matlab.ui.control.RadioButton
        DraworpaintButton               matlab.ui.control.RadioButton
        GolfButton                      matlab.ui.control.RadioButton
        PlaysoccerButton                matlab.ui.control.RadioButton
        PilatesmeditationButton         matlab.ui.control.RadioButton
        Q4WhoisyourfavoriteoftheseartistsButtonGroup  matlab.ui.container.ButtonGroup
        ABoogieButton                   matlab.ui.control.RadioButton
        SiaButton                       matlab.ui.control.RadioButton
        LewisCapaldiButton              matlab.ui.control.RadioButton
        SteveAokiButton                 matlab.ui.control.RadioButton
        LizzoButton                     matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            
score_harry = 0; %% INITIALIZE SCORES AND SET ALL TO 0
score_louis = 0;
score_niall = 0;
score_zayn = 0;
score_liam = 0;
app.WhichOneDirectionMemberAreYouPanel_Harry.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_zayn.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_louis.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_Liam.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_Niall.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible = 'off';
app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='off';
app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='off';
app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible='off';
app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible='off';
app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible='off';
app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='off';
app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible='off';

%%QUESTION 1
if app.fruitsandveggiesButton.Value == true
      score_harry = score_harry + 1;
elseif app.fastfoodButton.Value ==true
      score_louis = score_louis + 1;
elseif app.WhoneedsfoodwhenyouhavebeerButton.Value ==true
      score_niall = score_niall + 1;
elseif app.pastaButton.Value ==true
      score_zayn = score_zayn + 1;
else app.MexicanfoodButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 2
if app.summerButton.Value ==true
      score_harry = score_harry + 1;
elseif app.winterButton.Value ==true
      score_louis = score_louis + 1;
elseif app.springButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fallButton.Value ==true
      score_zayn = score_zayn + 1;
else app.IlikethemallButton.Value ==true;
      score_liam = score_liam + 4;
end

%%QUESTION 3
if app.iceskatingButton.Value ==true
      score_harry = score_harry + 1;
elseif app.goingforadriveButton.Value ==true
      score_louis = score_louis + 1;
elseif app.stayinginandplayinggamesButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fancydinnerdateButton.Value ==true
      score_zayn = score_zayn + 1;
else app.goingtoacarnivalButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 4
if app.LizzoButton.Value == true
     score_harry = score_harry + 1;
elseif app.SteveAokiButton.Value == true
     score_louis = score_louis + 1;
elseif app.LewisCapaldiButton.Value == true
     score_niall = score_niall + 1;
elseif app.SiaButton.Value== true
     score_zayn = score_zayn + 1;
else app.ABoogieButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 5
if app.PilatesmeditationButton.Value == true
     score_harry = score_harry + 1;
elseif app.PlaysoccerButton.Value == true
     score_louis = score_louis + 1;
elseif app.GolfButton.Value == true
     score_niall = score_niall + 1;
elseif app.DraworpaintButton.Value == true
     score_zayn = score_zayn + 1;
else app.GooutButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 6
if app.MomfriendButton.Value == true
     score_harry = score_harry + 1;
elseif app.ClassclownButton.Value == true
     score_louis = score_louis + 1;
elseif app.CrazyuncleButton.Value == true
     score_niall = score_niall + 1;
elseif app.TheonepeoplegotoforadviceButton.Value == true
     score_zayn = score_zayn + 1;
else app.DadfriendButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 7
if app.GreenButton.Value == true
   score_harry = score_harry + 1;
elseif app.BlueButton.Value == true
   score_louis = score_louis + 1;
elseif app.OrangeButton.Value == true
   score_niall = score_niall + 1;
elseif app.YellowButton.Value == true
   score_zayn = score_zayn + 1;
else app.RedButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 8
if app.CalicokittenButton.Value == true
   score_harry = score_harry + 1;
elseif app.AustralianshepherdpuppyButton.Value == true
   score_louis = score_louis + 1;
elseif app.GoldenretrieverpuppyButton.Value == true
   score_niall = score_niall + 1;
elseif app.BlackkittenButton.Value == true
   score_zayn = score_zayn + 1;
else app.BeaglepuppyButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 9
if app.LiteraryartsButton.Value == true
   score_harry = score_harry + 1;
elseif app.MCMButton.Value == true
   score_louis = score_louis + 1;
elseif app.HistoryButton.Value == true
   score_niall = score_niall + 1;
elseif app.VISAButton.Value == true
   score_zayn = score_zayn + 1;
else app.APMAEconwithanentrepreneurshipcertificateButton.Value == true;
   score_liam = score_liam + 1;
end

%%RESULTS SECTION
if score_harry > score_louis & score_harry > score_niall & score_harry > score_zayn & score_harry > score_liam;
   disp('Harry Styles!');
   app.WhichOneDirectionMemberAreYouPanel_Harry.Visible = 'on';
elseif score_louis > score_harry & score_louis > score_niall & score_louis > score_zayn & score_louis > score_liam;
   disp('Louis Tomlinson!');
   app.WhichOneDirectionMemberAreYouPanel_louis.Visible = 'on';
elseif score_niall > score_harry & score_niall > score_louis & score_niall > score_zayn & score_niall > score_liam;
   disp('Niall Horan!');
   app.WhichOneDirectionMemberAreYouPanel_Niall.Visible = 'on';
elseif score_zayn > score_harry & score_zayn > score_niall & score_zayn > score_liam & score_zayn > score_louis;
   disp('Zayn Malik!');
   app.WhichOneDirectionMemberAreYouPanel_zayn.Visible = 'on';
elseif score_liam > score_harry & score_liam > score_niall & score_liam > score_zayn & score_liam > score_louis;
   disp('Liam Payne!');
   app.WhichOneDirectionMemberAreYouPanel_Liam.Visible = 'on';
else score_harry==score_louis | score_harry==score_niall|score_harry==score_zayn|score_harry==score_liam|score_louis==score_niall|score_louis==score_zayn|score_louis==score_liam|score_niall==score_zayn|score_niall==score_liam|score_zayn==score_liam;
    disp('Taylor Swift')
    app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible = 'on';
end

        end

        % Callback function
        function continueharryButtonPushed(app, event)

        end

        % Button pushed function: SubmitHarryButton
        function SubmitHarryButtonPushed(app, event)
           app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='off';
            if app.HappyButton.Value==true && app.IminloveButton_6.Value==true
                app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='on';
            end
        end

        % Button pushed function: continueliamButton
        function continueliamButtonPushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_Liam.Visible='off';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible='on';
        end

        % Button pushed function: continuetaylorButton
        function continuetaylorButtonPushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible='off';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible='on';
        end

        % Button pushed function: continueniallButton
        function continueniallButtonPushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_Niall.Visible='off';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible='on';
        end

        % Button pushed function: continuelouisButton
        function continuelouisButtonPushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_louis.Visible='off';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='on';
        end

        % Button pushed function: continueButton_zayn
        function continueButton_zaynPushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_zayn.Visible='off';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible='on';
        end

        % Callback function
        function SubmitButton_2Pushed(app, event)
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='off';
            if app.HappyButton_2.Value==true && app.IminloveButton_5.Value==true
                app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='on';
            end
        end

        % Button pushed function: continueharryButton
        function continueharryButtonPushed2(app, event)
            app.WhichOneDirectionMemberAreYouPanel_Harry.Visible='off';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup = uibuttongroup(app.UIFigure);
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup.Title = 'Q4 Who is your favorite of these artists?';
            app.LizzoButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LizzoButton.Text = 'Lizzo';
            app.LizzoButton.Value = true;
            app.SteveAokiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SteveAokiButton.Text = 'Steve Aoki';
            app.LewisCapaldiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LewisCapaldiButton.Text = 'Lewis Capaldi';
            app.SiaButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SiaButton.Text = 'Sia';
            app.ABoogieButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.ABoogieButton.Text = 'A Boogie';
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup.Title = 'Q5 Which would you rather do in your free time?';
            app.PilatesmeditationButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PilatesmeditationButton.Text = 'Pilates & meditation';
            app.PilatesmeditationButton.Value = true;
            app.PlaysoccerButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PlaysoccerButton.Text = 'Play soccer';
            app.GolfButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GolfButton.Text = 'Golf';
            app.DraworpaintButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.DraworpaintButton.Text = 'Draw or paint';
            app.GooutButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GooutButton.Text = 'Go out';
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup = uibuttongroup(app.UIFigure);
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup.Title = 'Q6 Which role do you have in your friend group?';
            app.MomfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.MomfriendButton.Text = 'Mom friend';
            app.MomfriendButton.Value = true;
            app.ClassclownButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.ClassclownButton.Text = 'Class clown';
            app.CrazyuncleButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.CrazyuncleButton.Text = 'Crazy uncle';
            app.TheonepeoplegotoforadviceButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.TheonepeoplegotoforadviceButton.Text = 'The one people go to for advice';
            app.DadfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.DadfriendButton.Text = 'Dad friend';
            app.Q7WhatisyourfavoritecolorButtonGroup = uibuttongroup(app.UIFigure);
            app.Q7WhatisyourfavoritecolorButtonGroup.Title = 'Q7 What is your favorite color?';
            app.GreenButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.GreenButton.Value = true;
            app.BlueButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.OrangeButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.YellowButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.RedButton.Text = 'Red';
            app.Q8WhichpetwouldyouratherhaveButtonGroup = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup.Title = 'Q8 Which pet would you rather have?';
            app.CalicokittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.CalicokittenButton.Text = {'Calico kitten'; ''};
            app.CalicokittenButton.Value = true;
            app.AustralianshepherdpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.AustralianshepherdpuppyButton.Text = 'Australian shepherd puppy ';
            app.GoldenretrieverpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.GoldenretrieverpuppyButton.Text = 'Golden retriever puppy';
            app.BlackkittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BlackkittenButton.Text = 'Black kitten';
            app.BeaglepuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BeaglepuppyButton.Text = 'Beagle puppy ';
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3.Title = 'Q9 If you weren’t studying CLPS, what else would you want to study at Brown?';
            app.LiteraryartsButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.LiteraryartsButton.Text = 'Literary arts ';
            app.LiteraryartsButton.Value = true;
            app.MCMButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.MCMButton.Text = 'MCM';
            app.HistoryButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.HistoryButton.Text = 'History';
            app.VISAButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.VISAButton.Text = 'VISA';
            app.APMAEconwithanentrepreneurshipcertificateButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.APMAEconwithanentrepreneurshipcertificateButton.Text = 'APMA Econ with an entrepreneurship certificate ';
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup.Title = 'Q3 What would your ideal first date be?';
            app.iceskatingButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.iceskatingButton.Text = 'ice skating';
            app.iceskatingButton.Value = true;
            app.goingforadriveButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingforadriveButton.Text = 'going for a drive';
            app.stayinginandplayinggamesButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.stayinginandplayinggamesButton.Text = 'staying in and playing games';
            app.fancydinnerdateButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.fancydinnerdateButton.Text = 'fancy dinner date';
            app.goingtoacarnivalButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingtoacarnivalButton.Text = 'going to a carnival';
            app.Q2WhatisyourfavoriteseasonButtonGroup = uibuttongroup(app.UIFigure);
            app.Q2WhatisyourfavoriteseasonButtonGroup.Title = 'Q2 What is your favorite season?';
            app.summerButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.summerButton.Text = 'summer';
            app.summerButton.Value = true;
            app.winterButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.winterButton.Text = 'winter';
            app.springButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.springButton.Text = 'spring';
            app.fallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.fallButton.Text = 'fall';
            app.IlikethemallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.IlikethemallButton.Text = 'I like them all!';
            app.Q1WhatisyourfavoritefoodButtonGroup = uibuttongroup(app.UIFigure);
            app.Q1WhatisyourfavoritefoodButtonGroup.Title = 'Q1 What is your favorite food?';
            app.fruitsandveggiesButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fruitsandveggiesButton.Text = 'fruits and veggies';
            app.fruitsandveggiesButton.Value = true;
            app.fastfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fastfoodButton.Text = 'fast food';
            app.WhoneedsfoodwhenyouhavebeerButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.WhoneedsfoodwhenyouhavebeerButton.Text = 'Who needs food when you have beer?';
            app.pastaButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.pastaButton.Text = 'pasta!!';
            app.MexicanfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.MexicanfoodButton.Text = 'Mexican food';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.WhichOneDirectionMemberAreYouLabel = uilabel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouLabel.Text = 'Which One Direction Member Are You?';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_8323.JPG');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '73ef9ca68981d5688ce7673ec4001abb.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '68305cf6fa5c7a4b56bac69cd797e812.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'c45258f08ee609c62cb79d58ac394eba.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'cb1d109659d69e6bf0d3c9c9aa37934f.jpg');
            app.WhichOneDirectionMemberAreYouPanel_zayn = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_zayn.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_zayn.Visible = 'off';
            app.TextArea_2 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_zayn);
            app.TextArea_2.Value = {''; 'ZAYN MALIK'};
            app.continueButton_zayn = uibutton(app.WhichOneDirectionMemberAreYouPanel_zayn, 'push');
            app.continueButton_zayn.ButtonPushedFcn = createCallbackFcn(app, @continueButton_zaynPushed, true);
            app.continueButton_zayn.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_louis = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_louis.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_louis.Visible = 'off';
            app.TextArea_3 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_louis);
            app.TextArea_3.Value = {'LOUIS TOMLINSON'};
            app.continuelouisButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_louis, 'push');
            app.continuelouisButton.ButtonPushedFcn = createCallbackFcn(app, @continuelouisButtonPushed, true);
            app.continuelouisButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_Niall = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_Niall.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Niall.Visible = 'off';
            app.TextArea_5 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Niall);
            app.TextArea_5.Value = {''; 'NIALL HORAN'};
            app.continueniallButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Niall, 'push');
            app.continueniallButton.ButtonPushedFcn = createCallbackFcn(app, @continueniallButtonPushed, true);
            app.continueniallButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_Taylor = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_Taylor.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible = 'off';
            app.TextArea_6 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Taylor);
            app.TextArea_6.Value = {'omg?!? you''re NOT a 1D member... you''re TAYLOR SWIFT'};
            app.continuetaylorButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Taylor, 'push');
            app.continuetaylorButton.ButtonPushedFcn = createCallbackFcn(app, @continuetaylorButtonPushed, true);
            app.continuetaylorButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_Liam = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_Liam.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Liam.Visible = 'off';
            app.TextArea_4 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Liam);
            app.TextArea_4.Value = {''; 'LIAM PAYNE'};
            app.continueliamButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Liam, 'push');
            app.continueliamButton.ButtonPushedFcn = createCallbackFcn(app, @continueliamButtonPushed, true);
            app.continueliamButton.Text = 'continue';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Title = 'So you''re Louis Tomlinson...but what Louis Tomlinson song are you?';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_2 = uibuttongroup(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.HowareyoufeelingButtonGroup_2.Title = 'How are you feeling?';
            app.HappyButton_2 = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.HappyButton_2.Text = 'Happy';
            app.HappyButton_2.Value = true;
            app.SadButton_2 = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.SadButton_2.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_2 = uibuttongroup(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_2.Title = 'What''s your relationship status?';
            app.IminloveButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.IminloveButton_5.Text = 'I''m in love :)';
            app.IminloveButton_5.Value = true;
            app.Imheartbroken3Button_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.Imheartbroken3Button_5.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.SingleandchillingButton_5.Text = 'Single and chilling';
            app.ItscomplicatedButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.ItscomplicatedButton_5.Text = 'It''s complicated...';
            app.SubmitLouisButton = uibutton(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel, 'push');
            app.SubmitLouisButton.Text = 'Submit';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Title = 'So you''re happy and in love...here''s your Harry Styles song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible = 'off';
            app.EditField = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel, 'text');
            app.EditField.Value = 'Sunflower Vol. 6';
            app.Image6_12 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Image6_12.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.05.49 AM.png');
            app.Image6_13 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Image6_13.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.05.49 AM.png');
            app.Hyperlink2_3 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Hyperlink2_3.URL = 'https://open.spotify.com/track/6iYMfxznTBlcVOgRHab2W0?si=6ae3059c87134601';
            app.Hyperlink2_3.Text = 'click here to listen';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Title = 'So you''re Taylor Swift...but what Taylor Swift song are you?';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_6 = uibuttongroup(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel);
            app.HowareyoufeelingButtonGroup_6.Title = 'How are you feeling?';
            app.HappyButton_6 = uiradiobutton(app.HowareyoufeelingButtonGroup_6);
            app.HappyButton_6.Text = 'Happy';
            app.HappyButton_6.Value = true;
            app.SadButton_6 = uiradiobutton(app.HowareyoufeelingButtonGroup_6);
            app.SadButton_6.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_6 = uibuttongroup(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_6.Title = 'What''s your relationship status?';
            app.IminloveButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.IminloveButton_4.Text = 'I''m in love :)';
            app.IminloveButton_4.Value = true;
            app.Imheartbroken3Button_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.Imheartbroken3Button_4.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.SingleandchillingButton_4.Text = 'Single and chilling';
            app.ItscomplicatedButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.ItscomplicatedButton_4.Text = 'It''s complicated...';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Title = 'So you''re Zayn Malik...but what Zayn Malik song are you?';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_3 = uibuttongroup(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel);
            app.HowareyoufeelingButtonGroup_3.Title = 'How are you feeling?';
            app.HappyButton_3 = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.HappyButton_3.Text = 'Happy';
            app.HappyButton_3.Value = true;
            app.SadButton_3 = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.SadButton_3.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_3 = uibuttongroup(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_3.Title = 'What''s your relationship status?';
            app.IminloveButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.IminloveButton.Text = 'I''m in love :)';
            app.IminloveButton.Value = true;
            app.Imheartbroken3Button = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.Imheartbroken3Button.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.SingleandchillingButton.Text = 'Single and chilling';
            app.ItscomplicatedButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.ItscomplicatedButton.Text = 'It''s complicated...';
            app.SubmitZaynButton = uibutton(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel, 'push');
            app.SubmitZaynButton.Text = 'Submit';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Title = 'So you''re Niall Horan...but what Niall Horan song are you?';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_4 = uibuttongroup(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel);
            app.HowareyoufeelingButtonGroup_4.Title = 'How are you feeling?';
            app.HappyButton_4 = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.HappyButton_4.Text = 'Happy';
            app.HappyButton_4.Value = true;
            app.SadButton_4 = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.SadButton_4.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_4 = uibuttongroup(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_4.Title = 'What''s your relationship status?';
            app.IminloveButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.IminloveButton_2.Text = 'I''m in love :)';
            app.IminloveButton_2.Value = true;
            app.Imheartbroken3Button_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.Imheartbroken3Button_2.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.SingleandchillingButton_2.Text = 'Single and chilling';
            app.ItscomplicatedButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.ItscomplicatedButton_2.Text = 'It''s complicated...';
            app.SubmitNiallButton = uibutton(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel, 'push');
            app.SubmitNiallButton.Text = 'Submit';
            app.soyourehappyandinloveheresyourLouissongreccomendationPanel = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourLouissongreccomendationPanel.Title = 'So you''re happy and in love...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_2 = uieditfield(app.soyourehappyandinloveheresyourLouissongreccomendationPanel, 'text');
            app.EditField_2.Value = '    Always You';
            app.Image6_2 = uiimage(app.soyourehappyandinloveheresyourLouissongreccomendationPanel);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.35.30 AM.png');
            app.Hyperlink = uihyperlink(app.soyourehappyandinloveheresyourLouissongreccomendationPanel);
            app.Hyperlink.URL = 'https://open.spotify.com/track/5KcT9rJDqQJUSFF3O9wPJ5?si=9289be20e6a14c6e';
            app.Hyperlink.Text = 'click to listen!';
            app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel = uipanel(app.UIFigure);
            app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel.Title = 'So you''re happy and heartbroken...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_3 = uieditfield(app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel, 'text');
            app.EditField_3.Value = '       Headline';
            app.Image6_3 = uiimage(app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.45.55 AM.png');
            app.Hyperlink_2 = uihyperlink(app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel);
            app.Hyperlink_2.URL = 'https://open.spotify.com/track/1xRbWod9cdwCF0rF04LjDi?si=be4aeeaf81ba4d96';
            app.Hyperlink_2.Text = 'click to listen!';
            app.soyourehappyandsingleheresyourLouissongreccomendationPanel = uipanel(app.UIFigure);
            app.soyourehappyandsingleheresyourLouissongreccomendationPanel.Title = 'So you''re happy and single...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandsingleheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_4 = uieditfield(app.soyourehappyandsingleheresyourLouissongreccomendationPanel, 'text');
            app.EditField_4.Value = 'Silver Tounges';
            app.Image6_4 = uiimage(app.soyourehappyandsingleheresyourLouissongreccomendationPanel);
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.49.39 AM.png');
            app.Hyperlink_3 = uihyperlink(app.soyourehappyandsingleheresyourLouissongreccomendationPanel);
            app.Hyperlink_3.URL = 'https://open.spotify.com/track/29z96EFvG0L1wcj5kkIHzk?si=a3ec697c3c1f4b07d9cdwCF0rF04LjDi?si=be4aeeaf81ba4d96';
            app.Hyperlink_3.Text = 'click to listen!';
            app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel = uipanel(app.UIFigure);
            app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel.Title = 'So you''re happy and complicated...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_5 = uieditfield(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel, 'text');
            app.EditField_5.Value = 'Don''t Let It Break Your Heart';
            app.Image6_5 = uiimage(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel);
            app.Image6_5.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.52.16 AM.png');
            app.Hyperlink_4 = uihyperlink(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel);
            app.Hyperlink_4.URL = 'https://open.spotify.com/track/4nbQRLAPq202lsQsv9FNwK?si=b566e8c59bb04482';
            app.Hyperlink_4.Text = 'click to listen!';
            app.soyouresadandsingleheresyourLouissongreccomendationPanel = uipanel(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel);
            app.soyouresadandsingleheresyourLouissongreccomendationPanel.Title = 'So you''re sad and single...here''s your Louis Tomlin song recommendation!';
            app.soyouresadandsingleheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_8 = uieditfield(app.soyouresadandsingleheresyourLouissongreccomendationPanel, 'text');
            app.EditField_8.Value = 'Two of Us';
            app.Image6_8 = uiimage(app.soyouresadandsingleheresyourLouissongreccomendationPanel);
            app.Image6_8.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.01.15 AM.png');
            app.Hyperlink_7 = uihyperlink(app.soyouresadandsingleheresyourLouissongreccomendationPanel);
            app.Hyperlink_7.URL = 'https://open.spotify.com/track/1eqwjXdMTed8Pz3PLNcHe9?si=9a23bed036d140d9';
            app.Hyperlink_7.Text = 'click to listen!';
            app.soyouresadandinloveheresyourLouissongreccomendationPanel = uipanel(app.UIFigure);
            app.soyouresadandinloveheresyourLouissongreccomendationPanel.Title = 'So you''re sad and in love...here''s your Louis Tomlin song recommendation!';
            app.soyouresadandinloveheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_6 = uieditfield(app.soyouresadandinloveheresyourLouissongreccomendationPanel, 'text');
            app.EditField_6.Value = 'That''s The Way Love Goes';
            app.Image6_6 = uiimage(app.soyouresadandinloveheresyourLouissongreccomendationPanel);
            app.Image6_6.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.54.54 AM.png');
            app.Hyperlink_5 = uihyperlink(app.soyouresadandinloveheresyourLouissongreccomendationPanel);
            app.Hyperlink_5.URL = 'https://open.spotify.com/track/4o4f31HSyquyh0ANKJsgnb?si=4ce398e0529043b2';
            app.Hyperlink_5.Text = 'click to listen!';
            app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel = uipanel(app.UIFigure);
            app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel.Title = 'So you''re sad and complicated...here''s your Louis Tomlin song recommendation!';
            app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_7 = uieditfield(app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel, 'text');
            app.EditField_7.Value = 'Chicago';
            app.Image6_7 = uiimage(app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel);
            app.Image6_7.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.04.23 AM.png');
            app.Hyperlink_6 = uihyperlink(app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel);
            app.Hyperlink_6.URL = 'https://open.spotify.com/track/6ypAO1oxZUBVeHtdFOi3Cj?si=e8918d5ad2d3413b';
            app.Hyperlink_6.Text = 'click to listen!';
            app.soyourhappyandsingleHarryPanel = uipanel(app.UIFigure);
            app.soyourhappyandsingleHarryPanel.Title = 'So you''re happy and single...here''s your Harry Styles song recommendation!';
            app.soyourhappyandsingleHarryPanel.Visible = 'off';
            app.EditField_10 = uieditfield(app.soyourhappyandsingleHarryPanel, 'text');
            app.EditField_10.Value = 'Canyon Moon';
            app.Image6_14 = uiimage(app.soyourhappyandsingleHarryPanel);
            app.Image6_14.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.14.54 AM.png');
            app.Image6_15 = uiimage(app.soyourhappyandsingleHarryPanel);
            app.Image6_15.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.14.54 AM.png');
            app.Image6_16 = uiimage(app.soyourhappyandsingleHarryPanel);
            app.Image6_16.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.14.54 AM.png');
            app.Hyperlink2_4 = uihyperlink(app.soyourhappyandsingleHarryPanel);
            app.Hyperlink2_4.URL = 'https://open.spotify.com/track/3Dwq1oiOFys6Sxr5SslmXw?si=66fe45c6d8e94911';
            app.Hyperlink2_4.Text = 'click here to listen';
            app.soyourhappyandheartbrokenHarryPanel = uipanel(app.UIFigure);
            app.soyourhappyandheartbrokenHarryPanel.Title = 'So you''re happy and heartbroken...here''s your Harry Styles song recommendation!';
            app.soyourhappyandheartbrokenHarryPanel.Visible = 'off';
            app.EditField_9 = uieditfield(app.soyourhappyandheartbrokenHarryPanel, 'text');
            app.EditField_9.Value = 'Caronlina';
            app.Image6_9 = uiimage(app.soyourhappyandheartbrokenHarryPanel);
            app.Image6_9.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Image6_10 = uiimage(app.soyourhappyandheartbrokenHarryPanel);
            app.Image6_10.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Image6_11 = uiimage(app.soyourhappyandheartbrokenHarryPanel);
            app.Image6_11.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Hyperlink2_2 = uihyperlink(app.soyourhappyandheartbrokenHarryPanel);
            app.Hyperlink2_2.URL = 'https://open.spotify.com/track/3Dwq1oiOFys6Sxr5SslmXw?si=66fe45c6d8e94911';
            app.Hyperlink2_2.Text = 'click here to listen';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Title = 'So you''re Harry Styles...but what Harry Styles song are you?';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup = uibuttongroup(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.HowareyoufeelingButtonGroup.Title = 'How are you feeling?';
            app.HappyButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.HappyButton.Text = 'Happy';
            app.HappyButton.Value = true;
            app.SadButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.SadButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup = uibuttongroup(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup.Title = 'What''s your relationship status?';
            app.IminloveButton_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.IminloveButton_6.Text = 'I''m in love :)';
            app.IminloveButton_6.Value = true;
            app.Imheartbroken3Button_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.Imheartbroken3Button_6.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.SingleandchillingButton_6.Text = 'Single and chilling';
            app.ItscomplicatedButton_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.ItscomplicatedButton_6.Text = 'It''s complicated...';
            app.SubmitHarryButton = uibutton(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel, 'push');
            app.SubmitHarryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitHarryButtonPushed, true);
            app.SubmitHarryButton.Text = 'Submit';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel = uipanel(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Title = 'So you''re Liam Payne...but what Liam Payne song are you?';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_5 = uibuttongroup(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel);
            app.HowareyoufeelingButtonGroup_5.Title = 'How are you feeling?';
            app.HappyButton_5 = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.HappyButton_5.Text = 'Happy';
            app.HappyButton_5.Value = true;
            app.SadButton_5 = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.SadButton_5.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_5 = uibuttongroup(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_5.Title = 'What''s your relationship status?';
            app.IminloveButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.IminloveButton_3.Text = 'I''m in love :)';
            app.IminloveButton_3.Value = true;
            app.Imheartbroken3Button_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.Imheartbroken3Button_3.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.SingleandchillingButton_3.Text = 'Single and chilling';
            app.ItscomplicatedButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.ItscomplicatedButton_3.Text = 'It''s complicated...';
            app.SubmitLiamButton = uibutton(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel, 'push');
            app.SubmitLiamButton.Text = 'Submit';
            app.WhichOneDirectionMemberAreYouPanel_Harry = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_Harry.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Harry.Visible = 'off';
            app.TextArea_7 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Harry);
            app.TextArea_7.Value = {'HARRY STYLES'};
            app.continueharryButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Harry, 'push');
            app.continueharryButton.ButtonPushedFcn = createCallbackFcn(app, @continueharryButtonPushed2, true);
            app.continueharryButton.Text = 'continue';
            app.soyoursadandinloveHarryPanel = uipanel(app.UIFigure);
            app.soyoursadandinloveHarryPanel.Title = 'So you''re sad and in love...here''s your Harry Styles song recommendation!';
            app.soyoursadandinloveHarryPanel.Visible = 'off';
            app.EditField_12 = uieditfield(app.soyoursadandinloveHarryPanel, 'text');
            app.EditField_12.Value = 'Love of My Life';
            app.Image6_20 = uiimage(app.soyoursadandinloveHarryPanel);
            app.Image6_20.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.08.30 PM.png');
            app.Image6_21 = uiimage(app.soyoursadandinloveHarryPanel);
            app.Image6_21.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.08.30 PM.png');
            app.Hyperlink2_6 = uihyperlink(app.soyoursadandinloveHarryPanel);
            app.Hyperlink2_6.URL = 'https://open.spotify.com/track/1mw0RgNXIpYRyyCdBQbLgA?si=178e562ea5ef41f9';
            app.Hyperlink2_6.Text = 'click here to listen';
            app.soyoursadandheartbrokenHarryPanel = uipanel(app.UIFigure);
            app.soyoursadandheartbrokenHarryPanel.Title = 'So you''re sad and heartbroken...here''s your Harry Styles song recommendation!';
            app.soyoursadandheartbrokenHarryPanel.Visible = 'off';
            app.EditField_13 = uieditfield(app.soyoursadandheartbrokenHarryPanel, 'text');
            app.EditField_13.Value = 'Falling';
            app.Image6_22 = uiimage(app.soyoursadandheartbrokenHarryPanel);
            app.Image6_22.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.11.36 PM.png');
            app.Image6_23 = uiimage(app.soyoursadandheartbrokenHarryPanel);
            app.Image6_23.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.11.36 PM.png');
            app.Hyperlink2_7 = uihyperlink(app.soyoursadandheartbrokenHarryPanel);
            app.Hyperlink2_7.URL = 'https://open.spotify.com/track/1ZMiCix7XSAbfAJlEZWMCp?si=c50bbb86f7bc412c';
            app.Hyperlink2_7.Text = 'click here to listen';
            app.soyoursadandsingleHarryPanel = uipanel(app.UIFigure);
            app.soyoursadandsingleHarryPanel.Title = 'So you''re sad and single...here''s your Harry Styles song recommendation!';
            app.soyoursadandsingleHarryPanel.Visible = 'off';
            app.EditField_14 = uieditfield(app.soyoursadandsingleHarryPanel, 'text');
            app.EditField_14.Value = 'Fine Line';
            app.Image6_24 = uiimage(app.soyoursadandsingleHarryPanel);
            app.Image6_24.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.48.42 PM.png');
            app.Image6_25 = uiimage(app.soyoursadandsingleHarryPanel);
            app.Image6_25.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.48.42 PM.png');
            app.Hyperlink2_8 = uihyperlink(app.soyoursadandsingleHarryPanel);
            app.Hyperlink2_8.URL = 'https://open.spotify.com/track/6VzcQuzTNTMFnJ6rBSaLH9?si=e7acdf6bfc094ddfhttps://open.spotify.com/track/6VzcQuzTNTMFnJ6rBSaLH9?si=e7acdf6bfc094ddf';
            app.Hyperlink2_8.Text = 'click here to listen';
            app.soyoursadanditscomplicatedHarryPanel = uipanel(app.UIFigure);
            app.soyoursadanditscomplicatedHarryPanel.Title = 'So you''re sad and it''s complicated...here''s your Harry Styles song recommendation!';
            app.soyoursadanditscomplicatedHarryPanel.Visible = 'off';
            app.EditField_15 = uieditfield(app.soyoursadanditscomplicatedHarryPanel, 'text');
            app.EditField_15.Value = 'Girl Crush (cover)';
            app.Image6_26 = uiimage(app.soyoursadanditscomplicatedHarryPanel);
            app.Image6_26.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.55.57 PM.png');
            app.Image6_27 = uiimage(app.soyoursadanditscomplicatedHarryPanel);
            app.Image6_27.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.55.57 PM.png');
            app.Hyperlink2_9 = uihyperlink(app.soyoursadanditscomplicatedHarryPanel);
            app.Hyperlink2_9.URL = 'https://open.spotify.com/track/4YutJsNwBBInE8vemufpQ8?si=5f87420d1a73490d';
            app.Hyperlink2_9.Text = 'click here to listen';
            app.soyourhappyanditscomplicatedHarryPanel = uipanel(app.soyoursadanditscomplicatedHarryPanel);
            app.soyourhappyanditscomplicatedHarryPanel.Title = 'So you''re happy and complicated...here''s your Harry Styles song recommendation!';
            app.soyourhappyanditscomplicatedHarryPanel.Visible = 'off';
            app.EditField_11 = uieditfield(app.soyourhappyanditscomplicatedHarryPanel, 'text');
            app.EditField_11.Value = 'Satellite';
            app.Image6_17 = uiimage(app.soyourhappyanditscomplicatedHarryPanel);
            app.Image6_17.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Image6_18 = uiimage(app.soyourhappyanditscomplicatedHarryPanel);
            app.Image6_18.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Image6_19 = uiimage(app.soyourhappyanditscomplicatedHarryPanel);
            app.Image6_19.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Hyperlink2_5 = uihyperlink(app.soyourhappyanditscomplicatedHarryPanel);
            app.Hyperlink2_5.URL = 'https://open.spotify.com/track/0rzaRSujxA0bKyjJl6vHYq?si=c7e413df07aa408e';
            app.Hyperlink2_5.Text = 'click here to listen';
            app.soyourhappyandinloveNiallPanel = uipanel(app.UIFigure);
            app.soyourhappyandinloveNiallPanel.Title = 'So you''re happy and in love...here''s your Niall Horan song recommendation!';
            app.soyourhappyandinloveNiallPanel.Visible = 'off';
            app.EditField_16 = uieditfield(app.soyourhappyandinloveNiallPanel, 'text');
            app.EditField_16.Value = 'Heaven';
            app.Image6_28 = uiimage(app.soyourhappyandinloveNiallPanel);
            app.Image6_28.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.17.52 PM.png');
            app.Image6_29 = uiimage(app.soyourhappyandinloveNiallPanel);
            app.Image6_29.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.17.52 PM.png');
            app.Hyperlink2_10 = uihyperlink(app.soyourhappyandinloveNiallPanel);
            app.Hyperlink2_10.URL = 'https://open.spotify.com/track/1yHVHoz6Ny29gbbWJYVnFt?si=6e2d81b7c5eb4ab3';
            app.Hyperlink2_10.Text = 'click here to listen';
            app.soyourhappyandheartbrokenNiallPanel = uipanel(app.UIFigure);
            app.soyourhappyandheartbrokenNiallPanel.Title = 'So you''re happy and heartbroken...here''s your Niall Horan song recommendation!';
            app.soyourhappyandheartbrokenNiallPanel.Visible = 'off';
            app.EditField_17 = uieditfield(app.soyourhappyandheartbrokenNiallPanel, 'text');
            app.EditField_17.Value = 'Our Song';
            app.Image6_30 = uiimage(app.soyourhappyandheartbrokenNiallPanel);
            app.Image6_30.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.12.44 PM.png');
            app.Image6_31 = uiimage(app.soyourhappyandheartbrokenNiallPanel);
            app.Image6_31.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.12.44 PM.png');
            app.Hyperlink2_11 = uihyperlink(app.soyourhappyandheartbrokenNiallPanel);
            app.Hyperlink2_11.URL = 'https://open.spotify.com/track/5zqObw7wjBgL9TDiAymxPn?si=d90c7065bbda4da5';
            app.Hyperlink2_11.Text = 'click here to listen';
            app.soyourhappyandsingleNiallPanel = uipanel(app.UIFigure);
            app.soyourhappyandsingleNiallPanel.Title = 'So you''re happy and single...here''s your Niall Horan song recommendation!';
            app.soyourhappyandsingleNiallPanel.Visible = 'off';
            app.EditField_18 = uieditfield(app.soyourhappyandsingleNiallPanel, 'text');
            app.EditField_18.Value = 'On My Own';
            app.Image6_32 = uiimage(app.soyourhappyandsingleNiallPanel);
            app.Image6_32.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.28.25 PM.png');
            app.Image6_33 = uiimage(app.soyourhappyandsingleNiallPanel);
            app.Image6_33.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.28.25 PM.png');
            app.Hyperlink2_12 = uihyperlink(app.soyourhappyandsingleNiallPanel);
            app.Hyperlink2_12.URL = 'https://open.spotify.com/track/2JL820UpxG8mLGWt1jjNRx?si=ec856e4309bb4fb1';
            app.Hyperlink2_12.Text = 'click here to listen';
            app.soyourhappyanditscomplicatedNiallPanel = uipanel(app.UIFigure);
            app.soyourhappyanditscomplicatedNiallPanel.Title = 'So you''re happy and it''s complicated...here''s your Niall Horan song recommendation!';
            app.soyourhappyanditscomplicatedNiallPanel.Visible = 'off';
            app.EditField_19 = uieditfield(app.soyourhappyanditscomplicatedNiallPanel, 'text');
            app.EditField_19.Value = 'San Francisco';
            app.Image6_35 = uiimage(app.soyourhappyanditscomplicatedNiallPanel);
            app.Image6_35.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.28.08 PM.png');
            app.Image6_36 = uiimage(app.soyourhappyanditscomplicatedNiallPanel);
            app.Image6_36.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.28.08 PM.png');
            app.Hyperlink2_13 = uihyperlink(app.soyourhappyanditscomplicatedNiallPanel);
            app.Hyperlink2_13.URL = 'https://open.spotify.com/track/5iSkdzgBBtAAkaqULGPZUd?si=6ea2067e0b2146f2';
            app.Hyperlink2_13.Text = 'click here to listen';
            app.soyoursadandinloveNiallPanel = uipanel(app.UIFigure);
            app.soyoursadandinloveNiallPanel.Title = 'So you''re sad and in love...here''s your Niall Horan song recommendation!';
            app.soyoursadandinloveNiallPanel.Visible = 'off';
            app.EditField_20 = uieditfield(app.soyoursadandinloveNiallPanel, 'text');
            app.EditField_20.Value = 'Put A Little Love on Me';
            app.Image6_37 = uiimage(app.soyoursadandinloveNiallPanel);
            app.Image6_37.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.30.22 PM.png');
            app.Image6_38 = uiimage(app.soyoursadandinloveNiallPanel);
            app.Image6_38.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.30.22 PM.png');
            app.Hyperlink2_14 = uihyperlink(app.soyoursadandinloveNiallPanel);
            app.Hyperlink2_14.URL = 'https://open.spotify.com/track/1hCTxutVVYvggAQcUUUZAT?si=b3ed340eefab4f4f';
            app.Hyperlink2_14.Text = 'click here to listen';
            app.soyoursadandheartbrokenNiallpanel = uipanel(app.UIFigure);
            app.soyoursadandheartbrokenNiallpanel.Title = 'So you''re sad and heartbroken ...here''s your Niall Horan song recommendation!';
            app.soyoursadandheartbrokenNiallpanel.Visible = 'off';
            app.EditField_21 = uieditfield(app.soyoursadandheartbrokenNiallpanel, 'text');
            app.EditField_21.Value = 'Flicker';
            app.Image6_39 = uiimage(app.soyoursadandheartbrokenNiallpanel);
            app.Image6_39.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.33.03 PM.png');
            app.Image6_40 = uiimage(app.soyoursadandheartbrokenNiallpanel);
            app.Image6_40.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.33.03 PM.png');
            app.Hyperlink2_15 = uihyperlink(app.soyoursadandheartbrokenNiallpanel);
            app.Hyperlink2_15.URL = 'https://open.spotify.com/track/5BpmwiKR6ns1EtfCQRpNUc?si=f6ab28d077434332';
            app.Hyperlink2_15.Text = 'click here to listen';
            app.soyoursadandsingleNiallPanel = uipanel(app.UIFigure);
            app.soyoursadandsingleNiallPanel.Title = 'So you''re sad and single ...here''s your Niall Horan song recommendation!';
            app.soyoursadandsingleNiallPanel.Visible = 'off';
            app.EditField_22 = uieditfield(app.soyoursadandsingleNiallPanel, 'text');
            app.EditField_22.Value = 'Dear Patience';
            app.Image6_41 = uiimage(app.soyoursadandsingleNiallPanel);
            app.Image6_41.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.34.18 PM.png');
            app.Image6_42 = uiimage(app.soyoursadandsingleNiallPanel);
            app.Image6_42.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.34.18 PM.png');
            app.Hyperlink2_16 = uihyperlink(app.soyoursadandsingleNiallPanel);
            app.Hyperlink2_16.URL = 'https://open.spotify.com/track/0gwfuYQz0TauOajxH2V2Cn?si=3783d84e89a34464';
            app.Hyperlink2_16.Text = 'click here to listen';
            app.soyoursadanditscomplicatedNialPanel = uipanel(app.UIFigure);
            app.soyoursadanditscomplicatedNialPanel.Title = 'So you''re sad and it''s complicated ...here''s your Niall Horan song recommendation!';
            app.soyoursadanditscomplicatedNialPanel.Visible = 'off';
            app.EditField_23 = uieditfield(app.soyoursadanditscomplicatedNialPanel, 'text');
            app.EditField_23.Value = 'Too Much to Ask';
            app.Image6_43 = uiimage(app.soyoursadanditscomplicatedNialPanel);
            app.Image6_43.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.36.21 PM.png');
            app.Image6_44 = uiimage(app.soyoursadanditscomplicatedNialPanel);
            app.Image6_44.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.36.21 PM.png');
            app.Hyperlink2_17 = uihyperlink(app.soyoursadanditscomplicatedNialPanel);
            app.Hyperlink2_17.URL = 'https://open.spotify.com/track/39jXnbACjiaiTwwhsr5sY5?si=436e98c8ffe74546';
            app.Hyperlink2_17.Text = 'click here to listen';
            app.soyourhappyandinloveZaynPanel = uipanel(app.UIFigure);
            app.soyourhappyandinloveZaynPanel.Title = 'So you''re happy and in love...here''s your Zayn Malik song recommendation!';
            app.soyourhappyandinloveZaynPanel.Visible = 'off';
            app.EditField_24 = uieditfield(app.soyourhappyandinloveZaynPanel, 'text');
            app.EditField_24.Value = 'Tight Rope';
            app.Image6_45 = uiimage(app.soyourhappyandinloveZaynPanel);
            app.Image6_45.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.50.06 PM.png');
            app.Image6_46 = uiimage(app.soyourhappyandinloveZaynPanel);
            app.Image6_46.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.50.06 PM.png');
            app.Hyperlink2_18 = uihyperlink(app.soyourhappyandinloveZaynPanel);
            app.Hyperlink2_18.URL = 'https://open.spotify.com/track/45qIVmUXgkO6A52qOYWpQK?si=91a89d5915bd4b74';
            app.Hyperlink2_18.Text = 'click here to listen';
            app.soyourhappyandheartbrokenZaynPanel = uipanel(app.UIFigure);
            app.soyourhappyandheartbrokenZaynPanel.Title = 'So you''re happy and heartbroken...here''s your Zayn Malik song recommendation!';
            app.soyourhappyandheartbrokenZaynPanel.Visible = 'off';
            app.EditField_25 = uieditfield(app.soyourhappyandheartbrokenZaynPanel, 'text');
            app.EditField_25.Value = 'BeFoUr';
            app.Image6_47 = uiimage(app.soyourhappyandheartbrokenZaynPanel);
            app.Image6_47.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.58.06 PM.png');
            app.Image6_48 = uiimage(app.soyourhappyandheartbrokenZaynPanel);
            app.Image6_48.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.58.06 PM.png');
            app.Hyperlink2_19 = uihyperlink(app.soyourhappyandheartbrokenZaynPanel);
            app.Hyperlink2_19.URL = 'https://open.spotify.com/track/7ofgPlilLgcw6G7fd2zNpT?si=510b2214ab3c4612';
            app.Hyperlink2_19.Text = 'click here to listen';
            app.soyourhappyandsingleZaynPanel = uipanel(app.UIFigure);
            app.soyourhappyandsingleZaynPanel.Title = 'So you''re happy and single...here''s your Zayn Malik song recommendation!';
            app.soyourhappyandsingleZaynPanel.Visible = 'off';
            app.EditField_26 = uieditfield(app.soyourhappyandsingleZaynPanel, 'text');
            app.EditField_26.Value = 'Good Guy';
            app.Image6_49 = uiimage(app.soyourhappyandsingleZaynPanel);
            app.Image6_49.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 2.03.49 PM.png');
            app.Image6_50 = uiimage(app.soyourhappyandsingleZaynPanel);
            app.Image6_50.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 2.03.49 PM.png');
            app.Hyperlink2_20 = uihyperlink(app.soyourhappyandsingleZaynPanel);
            app.Hyperlink2_20.URL = 'https://open.spotify.com/track/2a8kWoRITJO751JGbYVvCo?si=4eeef27de2d44261';
            app.Hyperlink2_20.Text = 'click here to listen';
            app.soyourhappyanditscomplicatedZaynPanel = uipanel(app.UIFigure);
            app.soyourhappyanditscomplicatedZaynPanel.Title = 'So you''re happy and it''s complicated...here''s your Zayn Malik song recommendation!';
            app.soyourhappyanditscomplicatedZaynPanel.Visible = 'off';
            app.EditField_27 = uieditfield(app.soyourhappyanditscomplicatedZaynPanel, 'text');
            app.EditField_27.Value = 'SHE DON''T LOVE ME';
            app.Image6_51 = uiimage(app.soyourhappyanditscomplicatedZaynPanel);
            app.Image6_51.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 2.20.42 PM.png');
            app.Image6_52 = uiimage(app.soyourhappyanditscomplicatedZaynPanel);
            app.Image6_52.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 2.20.42 PM.png');
            app.Hyperlink2_21 = uihyperlink(app.soyourhappyanditscomplicatedZaynPanel);
            app.Hyperlink2_21.URL = 'https://open.spotify.com/track/4MaOXwqSgiwgngbeYePmu8?si=27630984b867456a';
            app.Hyperlink2_21.Text = 'click here to listen';
            app.soyourhappyandinloveZaynPanel_3 = uipanel(app.UIFigure);
            app.soyourhappyandinloveZaynPanel_3.Title = 'So you''re sad and in love...here''s your Zayn Malik song recommendation!';
            app.soyourhappyandinloveZaynPanel_3.Visible = 'off';
            app.EditField_28 = uieditfield(app.soyourhappyandinloveZaynPanel_3, 'text');
            app.EditField_28.Value = 'SHE DON''T LOVE ME';
            app.Image6_53 = uiimage(app.soyourhappyandinloveZaynPanel_3);
            app.Image6_53.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 2.20.42 PM.png');
            app.Image6_54 = uiimage(app.soyourhappyandinloveZaynPanel_3);
            app.Image6_54.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 2.20.42 PM.png');
            app.Hyperlink2_22 = uihyperlink(app.soyourhappyandinloveZaynPanel_3);
            app.Hyperlink2_22.URL = 'https://open.spotify.com/track/4MaOXwqSgiwgngbeYePmu8?si=27630984b867456a';
            app.Hyperlink2_22.Text = 'click here to listen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design_page_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end